classdef guiex2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1   matlab.ui.Figure
        Filemenu  matlab.ui.container.Menu
        readmenu  matlab.ui.container.Menu
        exitmenu  matlab.ui.container.Menu
        axes1     matlab.ui.control.UIAxes
        listbox1  matlab.ui.control.ListBox
        slider1   matlab.ui.control.Slider
    end


    properties (Access = public)
        volt % Description
        temp
        pres % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function guiex2_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to guiex2 (see VARARGIN)
%             
%             % Choose default command line output for guiex2
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
 
        end

        % Menu selected function: readmenu
        function readmenuMenuSelected(app, event)
            global temp pres volt

            % hObject    handle to readmenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % we will open a file-pick dialog and check the returned file.
             allowedfiles={'*.dat' ,'Data Files' ; '*.txt' , 'Text Files' } ;
             [ fname , pathname ] = uigetfile (allowedfiles ,'Data Source' );
             fname = fullfile ( pathname , fname );
             A= importdata( fname  );
             app.temp = A(:,1); app.pres = A(:,2) ; app.volt = A(:,3);
             plot(app.axes1,  temp)
        end

        % Value changed function: listbox1
        function listbox1ValueChanged(app, event)
            value = app.listbox1.Value;
            plot(app.axes1,  app.volt)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'guiex2';
            app.Filemenu = uimenu(app.figure1);
            app.Filemenu.Accelerator = 'F';
            app.Filemenu.Text = 'File';
            app.readmenu = uimenu(app.Filemenu);
            app.readmenu.MenuSelectedFcn = createCallbackFcn(app, @readmenuMenuSelected, true);
            app.readmenu.Accelerator = 'R';
            app.readmenu.Text = 'Read';
            app.exitmenu = uimenu(app.Filemenu);
            app.exitmenu.Accelerator = 'Q';
            app.exitmenu.Text = 'Exit';
            app.axes1 = uiaxes(app.figure1);
            app.listbox1 = uilistbox(app.figure1);
            app.listbox1.Items = {'temp', 'pres', 'vol'};
            app.listbox1.ItemsData = {'temp', 'pres', 'vol'};
            app.listbox1.ValueChangedFcn = createCallbackFcn(app, @listbox1ValueChanged, true);
            app.listbox1.Value = 'temp';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [0 1];
            app.slider1.MajorTicks = [];
            app.slider1.MinorTicks = [];
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiex2_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @guiex2_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end